classdef comb_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CombFilterAnalysisTab          matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        DelayestimationPanel           matlab.ui.container.Panel
        SamplingfreqButtonGroup        matlab.ui.container.ButtonGroup
        kHzButton                      matlab.ui.control.ToggleButton
        kHzButton_2                    matlab.ui.control.ToggleButton
        DistanceEditField              matlab.ui.control.NumericEditField
        MicdistancemKnobLabel          matlab.ui.control.Label
        MicdistancemKnob               matlab.ui.control.Knob
        TemperatureCLabel              matlab.ui.control.Label
        TemperatureCEditField          matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        samplesEditFieldLabel          matlab.ui.control.Label
        samplesEditField               matlab.ui.control.NumericEditField
        msEditFieldLabel               matlab.ui.control.Label
        msEditField                    matlab.ui.control.NumericEditField
        TestcombfilterPanel            matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        FilelocationEditField          matlab.ui.control.EditField
        OpenfileButton                 matlab.ui.control.Button
        ApplyfilterButton              matlab.ui.control.Button
        SavedfileEditFieldLabel        matlab.ui.control.Label
        SavedfileEditField             matlab.ui.control.EditField
        PlayoriginalButton             matlab.ui.control.Button
        PlayfilteredButton             matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ClearallButton                 matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        PhasealignmentTab_2            matlab.ui.container.Tab
        Signal1EditField_2Label        matlab.ui.control.Label
        Signal1EditField_2             matlab.ui.control.EditField
        Signal2EditField_2Label        matlab.ui.control.Label
        Signal2EditField_2             matlab.ui.control.EditField
        Signal3EditField_2Label        matlab.ui.control.Label
        Signal3EditField_2             matlab.ui.control.EditField
        Signal4EditField_2Label        matlab.ui.control.Label
        Signal4EditField_2             matlab.ui.control.EditField
        OpenfileButton_6               matlab.ui.control.Button
        OpenfileButton_7               matlab.ui.control.Button
        OpenfileButton_8               matlab.ui.control.Button
        OpenfileButton_9               matlab.ui.control.Button
        SamplingfreqButtonGroup_3      matlab.ui.container.ButtonGroup
        kHzButton_5                    matlab.ui.control.ToggleButton
        kHzButton_6                    matlab.ui.control.ToggleButton
        Correlation12EditField_2Label  matlab.ui.control.Label
        Correlation12EditField_2       matlab.ui.control.NumericEditField
        Correlation13EditField_2Label  matlab.ui.control.Label
        Correlation13EditField_2       matlab.ui.control.NumericEditField
        Correlation23EditField_2Label  matlab.ui.control.Label
        Correlation23EditField_2       matlab.ui.control.NumericEditField
        Correlation14EditField_2Label  matlab.ui.control.Label
        Correlation14EditField_2       matlab.ui.control.NumericEditField
        ClearallButton_3               matlab.ui.control.Button
        AnalyseButton_2                matlab.ui.control.Button
        AlignButton_2                  matlab.ui.control.Button
        TestPanel_2                    matlab.ui.container.Panel
        MixButton                      matlab.ui.control.Button
        MixButton_2                    matlab.ui.control.Button
        OriginalsignalsEditFieldLabel  matlab.ui.control.Label
        OriginalsignalsEditField       matlab.ui.control.EditField
        AlignedsignalsEditFieldLabel   matlab.ui.control.Label
        AlignedsignalsEditField        matlab.ui.control.EditField
        Correlation34EditField_2Label  matlab.ui.control.Label
        Correlation34EditField_2       matlab.ui.control.NumericEditField
        DelaySignal1EditField_2Label   matlab.ui.control.Label
        DelaySignal1EditField_2        matlab.ui.control.NumericEditField
        DelaySignal2EditField_2Label   matlab.ui.control.Label
        DelaySignal2EditField_2        matlab.ui.control.NumericEditField
        DelaySignal3EditField_2Label   matlab.ui.control.Label
        DelaySignal3EditField_2        matlab.ui.control.NumericEditField
        DelaySignal4EditField_2Label   matlab.ui.control.Label
        DelaySignal4EditField_2        matlab.ui.control.NumericEditField
        samplesLabel_2                 matlab.ui.control.Label
        samplesLabel_3                 matlab.ui.control.Label
        samplesLabel_4                 matlab.ui.control.Label
        samplesLabel_5                 matlab.ui.control.Label
        msEditField_2Label             matlab.ui.control.Label
        msEditField_2                  matlab.ui.control.NumericEditField
        msEditField_3Label             matlab.ui.control.Label
        msEditField_3                  matlab.ui.control.NumericEditField
        msEditField_4Label             matlab.ui.control.Label
        msEditField_4                  matlab.ui.control.NumericEditField
        msEditField_5Label             matlab.ui.control.Label
        msEditField_5                  matlab.ui.control.NumericEditField
        AlignedSignal1EditFieldLabel   matlab.ui.control.Label
        AlignedSignal1EditField        matlab.ui.control.EditField
        AlignedSignal2EditFieldLabel   matlab.ui.control.Label
        AlignedSignal2EditField        matlab.ui.control.EditField
        AlignedSignal3EditFieldLabel   matlab.ui.control.Label
        AlignedSignal3EditField        matlab.ui.control.EditField
        AlignedSignal4EditFieldLabel   matlab.ui.control.Label
        AlignedSignal4EditField        matlab.ui.control.EditField
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        MaxmicdistancemEditFieldLabel  matlab.ui.control.Label
        MaxmicdistancemEditField       matlab.ui.control.NumericEditField
        RoomtemperatureEditFieldLabel  matlab.ui.control.Label
        RoomtemperatureEditField       matlab.ui.control.NumericEditField
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        samplingFrequencyTab1 = 44100  % Hz
        samplingFrequencyTab2 = 44100  % Hz
        samplingPeriodTab1 = 0.02268   % ms
        samplingPeriodTab2 = 0.02268   % ms
        soundSpeedTab1 = 343.3
        soundSpeedTab2 = 343.3
        filePathTab1 = ""
        filePathTab2_1 = ""
    end

    methods (Access = private)

        function results = FindLags(app, corrSize, b)
            found = 0;
            Abase = [1 -1 0 0; 1 0 -1 0; 0 1 -1 0; 1 0 0 -1; 0 0 1 -1];
            Acomp = [1 0 0 0 ;0 1 0 0; 0 0 1 0; 0 0 0 1];
            if corrSize == 4
                Abase = [1 -1 0 0; 1 0 -1 0; 0 1 -1 0; 1 0 0 -1; 0 0 1 -1];
                Acomp = [1 0 0 0 ;0 1 0 0; 0 0 1 0; 0 0 0 1];
                %b = [b; 0];
            elseif corrSize == 3
                    Abase = [1 -1 0; 1 0 -1; 0 1 -1];
                    Acomp = [1 0 0; 0 1 0; 0 0 1]; 
            end
            
            for i=1:corrSize
                found = 0;
                A = vertcat(Abase,Acomp(i,:));
                lagsVector = round(A\b);
            
                for j=1:length(lagsVector)
                    if lagsVector(j) >= 0
                         found = found + 1;
                    end
                end
                if found == corrSize
                    break
                end     
            end
            if found > 0
                results = lagsVector;
            else
                results = [0 0 0 0];
            end
        end

        function DisplayDelays(app, corrSize, signalsLagVector)
            app.Lamp_2.Color = [0.902 0.902 0.902]; % grey
            app.Lamp_3.Color = [0.902 0.902 0.902]; % grey
            app.Lamp_4.Color = [0.902 0.902 0.902]; % grey
            app.Lamp_5.Color = [0.902 0.902 0.902]; % grey
            
            app.DelaySignal1EditField_2.Value = signalsLagVector(1);
            app.msEditField_2.Value = app.DelaySignal1EditField_2.Value * app.samplingPeriodTab2;
            if signalsLagVector(1) == 0
                app.Lamp_2.Color = [0 1 0]; % green
            end
            
            app.DelaySignal2EditField_2.Value = signalsLagVector(2);
            app.msEditField_3.Value = app.DelaySignal2EditField_2.Value * app.samplingPeriodTab2;
            if signalsLagVector(2) == 0
                app.Lamp_3.Color = [0 1 0]; % green
            end
            if corrSize == 3 || corrSize == 4
                app.DelaySignal3EditField_2.Value = signalsLagVector(3);
                app.msEditField_4.Value = app.DelaySignal3EditField_2.Value * app.samplingPeriodTab2;
                if signalsLagVector(3) == 0
                    app.Lamp_4.Color = [0 1 0]; % green
                end
            end
            if corrSize == 4
                app.DelaySignal4EditField_2.Value = signalsLagVector(4);
                app.msEditField_5.Value = app.DelaySignal4EditField_2.Value * app.samplingPeriodTab2;
                if signalsLagVector(4) == 0
                    app.Lamp_5.Color = [0 1 0]; % green
                end
            end
        end
        
    end
    

    methods (Access = private)

        % Selection changed function: SamplingfreqButtonGroup
        function SamplingfreqButtonGroupSelectionChanged(app, event)
            selectedButton = app.SamplingfreqButtonGroup.SelectedObject;
            if app.kHzButton == selectedButton
                app.samplingFrequencyTab1 = 44100;
            else
                app.samplingFrequencyTab1 = 48000;
            end
            app.samplingPeriodTab1 = (1/app.samplingFrequencyTab1)*1000;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            totalDelayTime = (app.DistanceEditField.Value/app.soundSpeedTab1)*1000; % ms
            app.msEditField.Value = totalDelayTime;
            samples = round(totalDelayTime/app.samplingPeriodTab1);
            app.samplesEditField.Value = samples;
            
            fs = 44100; %sampling rate
            g = 1; %feedback gain
            a = 1;
            if app.samplesEditField.Value == 0
                b = 1;
            else
                b = [1 zeros(1, app.samplesEditField.Value) g];
            end
            [h,w] = freqz(b,a,4096);
            f = (w/(2*pi))*fs;
            f_ = f(3:length(f)/4);
            h_ = h(3:length(h)/4);
            plot(app.UIAxes,f_,20*log10(abs(h_)));
            
            f_ = f_(3:length(f)/8);
            h_ = h_(3:length(h)/8);
            plot(app.UIAxes_2,f_,20*log10(abs(h_)));
            app.UIAxes_2.XScale = 'log';
            
        end

        % Value changed function: MicdistancemKnob
        function MicdistancemKnobValueChanged(app, event)
            app.DistanceEditField.Value = app.MicdistancemKnob.Value;            
        end

        % Value changed function: TemperatureCEditField
        function TemperatureCEditFieldValueChanged(app, event)
            value = app.TemperatureCEditField.Value;
            app.soundSpeedTab1 = 331.3 + (0.6 * value); % in m/s
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            if app.DistanceEditField.Value <= 3
                app.MicdistancemKnob.Value = app.DistanceEditField.Value;
            else
                uialert(app.UIFigure,'Invalid argument','Outside range of 3 meters');
                app.MicdistancemKnob.Value = 0;
                app.DistanceEditField.Value = 0;
            end
        end

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            [name,path] = uigetfile('*.wav', 'Select a wave file');
            if path == 0
                app.filePathTab1 = "";
            else
                fullFileName = fullfile(path, name);

                app.FilelocationEditField.Value = fullFileName;
                app.filePathTab1 = fullFileName;
            end
        end

        % Value changed function: FilelocationEditField
        function FilelocationEditFieldValueChanged(app, event)
            app.filePathTab1 = app.FilelocationEditField.Value;
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            app.Lamp.Color = [1 0 0]; % red
            if app.filePathTab1 ~= ""
                app.Lamp.Color = [1 0 0]; % red
                [original_audio_file,Fs] = audioread(app.filePathTab1);
                %error(msg)
                g = 0.9; %feedback gain
                a = 1;
                if app.samplesEditField.Value == 0
                    b = 1;
                else
                    b = [1 zeros(1, app.samplesEditField.Value) g];
                end
                
                [filtered_audio_file] = filter(b,a,original_audio_file); % apply filter to audio file
                audiowrite('filtered_file.wav', filtered_audio_file, Fs);
                app.SavedfileEditField.Value = which('filtered_file.wav');
                app.Lamp.Color = [0 1 0]; % green
            end
        end

        % Button pushed function: PlayoriginalButton
        function PlayoriginalButtonPushed(app, event)
            [y,Fs] = audioread(app.filePathTab1);
            sound(y,Fs)
        end

        % Button pushed function: PlayfilteredButton
        function PlayfilteredButtonPushed(app, event)
            [y,Fs] = audioread(app.SavedfileEditField.Value);
            sound(y,Fs)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear playsnd
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.Lamp.Color = [0.902 0.902 0.902]; % grey
            app.samplingFrequencyTab1 = 44100;  % Hz
            app.samplingPeriodTab1 = 0.02268;   % ms
            app.soundSpeedTab1 = 343.3;
            app.TemperatureCEditField.Value = 20;
            app.DistanceEditField.Value = 0;
            app.MicdistancemKnob.Value = 0;
            app.SamplingfreqButtonGroup.SelectedObject = app.kHzButton;
            app.msEditField.Value = 0;
            app.samplesEditField.Value = 0;
            app.UIAxes.cla
            app.UIAxes_2.cla
            delete 'filtered_file.wav'
            app.filePathTab1 = "";
            app.FilelocationEditField.Value = "";
            app.SavedfileEditField.Value = "";
            
        end

        % Button pushed function: ClearallButton_3
        function ClearallButton_2Pushed(app, event)
            app.Signal1EditField_2.Value = "";
            app.Signal2EditField_2.Value = "";
            app.Signal3EditField_2.Value = "";
            app.Signal4EditField_2.Value = "";
            app.samplingFrequencyTab2 = 44100;
            app.samplingPeriodTab2 = 0.02268;
            app.soundSpeedTab2 = 343.3;
            app.RoomtemperatureEditField.Value = 20;
            app.MaxmicdistancemEditField.Value = 1;
            
            app.DelaySignal1EditField_2.Value = 0;
            app.DelaySignal2EditField_2.Value = 0;
            app.DelaySignal3EditField_2.Value = 0;
            app.DelaySignal4EditField_2.Value = 0;

            app.msEditField_2.Value = 0;
            app.msEditField_3.Value = 0;
            app.msEditField_4.Value = 0;                                  
            app.msEditField_5.Value = 0;
            
            app.Lamp_2.Color = [0.902 0.902 0.902]; % grey
            app.Lamp_3.Color = [0.902 0.902 0.902]; % grey
            app.Lamp_4.Color = [0.902 0.902 0.902]; % grey
            app.Lamp_5.Color = [0.902 0.902 0.902]; % grey
        end

        % Button pushed function: OpenfileButton_6
        function OpenfileButton_6Pushed(app, event)
            [name,path] = uigetfile('*.wav', 'Select a wave file');
            if path == 0
                app.filePathTab1 = "";
            else
                fullFileName = fullfile(path, name);
                app.Signal1EditField_2.Value = fullFileName;
            end
        end

        % Button pushed function: OpenfileButton_7
        function OpenfileButton_7Pushed(app, event)
            [name,path] = uigetfile('*.wav', 'Select a wave file');
            if path == 0
                app.filePathTab1 = "";
            else
                fullFileName = fullfile(path, name);
                app.Signal2EditField_2.Value = fullFileName;
            end
        end

        % Button pushed function: OpenfileButton_8
        function OpenfileButton_8Pushed(app, event)
            [name,path] = uigetfile('*.wav', 'Select a wave file');
            if path == 0
                app.filePathTab1 = "";
            else
                fullFileName = fullfile(path, name);
                app.Signal3EditField_2.Value = fullFileName;
            end
        end

        % Button pushed function: OpenfileButton_9
        function OpenfileButton_9Pushed(app, event)
            [name,path] = uigetfile('*.wav', 'Select a wave file');
            if path == 0
                app.filePathTab1 = "";
            else
                fullFileName = fullfile(path, name);
                app.Signal4EditField_2.Value = fullFileName;
            end
        end

        % Selection changed function: SamplingfreqButtonGroup_3
        function SamplingfreqButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.SamplingfreqButtonGroup_3.SelectedObject;
            if app.kHzButton == selectedButton
                app.samplingFrequencyTab2 = 44100;
            else
                app.samplingFrequencyTab2 = 48000;
            end
            app.samplingPeriodTab2 = (1/app.samplingFrequencyTab2)*1000;
        end

        % Callback function
        function RoomtemperatureCEditFieldValueChanged(app, event)
            value = app.RoomtemperatureCEditField.Value;
            app.soundSpeedTab2 = 331.3 + (0.6 * value); % in m/s
        end

        % Value changed function: MaxmicdistancemEditField
        function MaxmicdistancemEditFieldValueChanged(app, event)
            app.MaxmicdistancemEditField.Value = app.MaxmicdistancemEditField.Value;
            
        end

        % Button pushed function: AnalyseButton_2
        function AnalyseButton_2Pushed(app, event)
            corrSize = 0;

            % Calculate cross-correlation window
            maxDelayTime = (app.MaxmicdistancemEditField.Value/app.soundSpeedTab2)*1000; % max delay in ms
            maxLag = round(maxDelayTime/app.samplingPeriodTab2); %max num of samples 
            
            if app.Signal1EditField_2.Value ~= ""
                [originalSignal1,Fs] = audioread(app.Signal1EditField_2.Value);
                if app.Signal2EditField_2.Value ~= ""    
                    [originalSignal2,Fs] = audioread(app.Signal2EditField_2.Value);
                    corrSize = 2;
                    if app.Signal2EditField_2.Value ~= ""    
                        [originalSignal3,Fs] = audioread(app.Signal3EditField_2.Value);
                        corrSize = 3;
                        A = [1 -1 0; 1 0 -1; 0 1 -1];
                        if app.Signal2EditField_2.Value ~= ""    
                            [originalSignal4,Fs] = audioread(app.Signal4EditField_2.Value);
                            corrSize = 4;
                            
                        end
                    end 

                end 
            end
            
            %if corrSize == 4 || corrSize == 3 || corrSize == 2
                [xc1,vector1] = xcorr(originalSignal1,originalSignal2,maxLag);
                [maxCrossValue,pos] = max(xc1); 
                app.Correlation12EditField_2.Value = maxCrossValue;
                lag1 = vector1(1,pos); % Lag in samples
                
                corrLagVector = lag1;
            %end  
            if corrSize == 4 || corrSize == 3
                [xc2,vector2] = xcorr(originalSignal1,originalSignal3,maxLag);
                [maxCrossValue,pos] = max(xc2); 
                app.Correlation13EditField_2.Value = maxCrossValue;
                lag2 = vector2(1,pos); % Lag in samples
                
                [xc3,vector3] = xcorr(originalSignal2,originalSignal3,maxLag);
                [maxCrossValue,pos] = max(xc3); 
                app.Correlation23EditField_2.Value = maxCrossValue;
                lag3 = vector3(1,pos); % Lag in samples
                
                corrLagVector = [lag1; lag2; lag3; 0];
            end   
            if corrSize == 4
                [xc4,vector4] = xcorr(originalSignal1,originalSignal4,maxLag);
                [maxCrossValue,pos] = max(xc4); 
                app.Correlation14EditField_2.Value = maxCrossValue;
                lag4 = vector4(1,pos); % Lag in samples
                
                [xc5,vector5] = xcorr(originalSignal3,originalSignal4,maxLag);
                [maxCrossValue,pos] = max(xc5); 
                app.Correlation34EditField_2.Value = maxCrossValue;
                lag5 = vector5(1,pos); % Lag in samples
                
                corrLagVector = [lag1; lag2; lag3; lag4; lag5; 0];
            end   
                signalsLagVector = FindLags(app, corrSize, corrLagVector);
                DisplayDelays(app, corrSize, signalsLagVector);
                
%                 app.DelaySignal1EditField_2.Value = signalsLagVector(1);
%                 app.msEditField_2.Value = app.DelaySignal1EditField_2.Value * app.samplingPeriodTab2;
%                 if signalsLagVector(1) == 0 
%                     app.Lamp_2.Color = [0 1 0]; % green 
%                 end
%                 
%                 app.DelaySignal2EditField_2.Value = signalsLagVector(2);
%                 app.msEditField_3.Value = app.DelaySignal2EditField_2.Value * app.samplingPeriodTab2;
%                 if signalsLagVector(2) == 0 
%                     app.Lamp_3.Color = [0 1 0]; % green 
%                 end
%                 
%                 app.DelaySignal3EditField_2.Value = signalsLagVector(3);
%                 app.msEditField_4.Value = app.DelaySignal3EditField_2.Value * app.samplingPeriodTab2;
%                 if signalsLagVector(3) == 0 
%                     app.Lamp_4.Color = [0 1 0]; % green 
%                 end
%                 
%                 app.DelaySignal4EditField_2.Value = signalsLagVector(4);
%                 app.msEditField_5.Value = app.DelaySignal4EditField_2.Value * app.samplingPeriodTab2;
%                 if signalsLagVector(4) == 0 
%                     app.Lamp_5.Color = [0 1 0]; % green 
%                 end


            %else if corrSize == 3
            
            
            %end
            
                
            end 
%                 audiowrite('filtered_file.wav', filtered_audio_file, Fs);
%                 app.SavedfileEditField.Value = which('filtered_file.wav');
%                 app.Lamp.Color = [0 1 0]; % green
        end

        % Value changed function: RoomtemperatureEditField
        function RoomtemperatureEditFieldValueChanged(app, event)
            value = app.RoomtemperatureEditField.Value;
            app.soundSpeedTab2 = 331.3 + (0.6 * value); % in m/s
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CombFilterAnalysisTab = uitab(app.TabGroup);
            app.CombFilterAnalysisTab.Title = 'Comb Filter Analysis';
            app.UIAxes = uiaxes(app.CombFilterAnalysisTab);
            title(app.UIAxes, 'Linear')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.CombFilterAnalysisTab);
            title(app.UIAxes_2, 'Log')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.DelayestimationPanel = uipanel(app.CombFilterAnalysisTab);
            app.DelayestimationPanel.Title = 'Delay estimation';
            app.SamplingfreqButtonGroup = uibuttongroup(app.DelayestimationPanel);
            app.SamplingfreqButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplingfreqButtonGroupSelectionChanged, true);
            app.SamplingfreqButtonGroup.Title = 'Sampling freq';
            app.kHzButton = uitogglebutton(app.SamplingfreqButtonGroup);
            app.kHzButton.Text = '44.1 kHz';
            app.kHzButton.Value = true;
            app.kHzButton_2 = uitogglebutton(app.SamplingfreqButtonGroup);
            app.kHzButton_2.Text = '48 kHz';
            app.DistanceEditField = uieditfield(app.DelayestimationPanel, 'numeric');
            app.DistanceEditField.ValueDisplayFormat = '%.2f';
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.MicdistancemKnobLabel = uilabel(app.DelayestimationPanel);
            app.MicdistancemKnobLabel.Text = 'Mic distance (m)';
            app.MicdistancemKnob = uiknob(app.DelayestimationPanel, 'continuous');
            app.MicdistancemKnob.Limits = [0 3];
            app.MicdistancemKnob.ValueChangedFcn = createCallbackFcn(app, @MicdistancemKnobValueChanged, true);
            app.TemperatureCLabel = uilabel(app.DelayestimationPanel);
            app.TemperatureCLabel.Text = 'Temperature (°C)';
            app.TemperatureCEditField = uieditfield(app.DelayestimationPanel, 'numeric');
            app.TemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureCEditFieldValueChanged, true);
            app.TemperatureCEditField.Value = 20;
            app.CalculateButton = uibutton(app.DelayestimationPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.samplesEditFieldLabel = uilabel(app.DelayestimationPanel);
            app.samplesEditFieldLabel.Text = 'samples';
            app.samplesEditField = uieditfield(app.DelayestimationPanel, 'numeric');
            app.samplesEditField.Editable = 'off';
            app.msEditFieldLabel = uilabel(app.DelayestimationPanel);
            app.msEditFieldLabel.Text = 'ms';
            app.msEditField = uieditfield(app.DelayestimationPanel, 'numeric');
            app.msEditField.Editable = 'off';
            app.TestcombfilterPanel = uipanel(app.CombFilterAnalysisTab);
            app.TestcombfilterPanel.Title = 'Test comb filter:';
            app.Label = uilabel(app.TestcombfilterPanel);
            app.Label.Text = '';
            app.FilelocationEditField = uieditfield(app.TestcombfilterPanel, 'text');
            app.FilelocationEditField.ValueChangedFcn = createCallbackFcn(app, @FilelocationEditFieldValueChanged, true);
            app.OpenfileButton = uibutton(app.TestcombfilterPanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.ApplyfilterButton = uibutton(app.TestcombfilterPanel, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.SavedfileEditFieldLabel = uilabel(app.TestcombfilterPanel);
            app.SavedfileEditFieldLabel.Text = 'Saved file:';
            app.SavedfileEditField = uieditfield(app.TestcombfilterPanel, 'text');
            app.SavedfileEditField.Editable = 'off';
            app.PlayoriginalButton = uibutton(app.TestcombfilterPanel, 'push');
            app.PlayoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalButtonPushed, true);
            app.PlayoriginalButton.Text = 'Play original';
            app.PlayfilteredButton = uibutton(app.TestcombfilterPanel, 'push');
            app.PlayfilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayfilteredButtonPushed, true);
            app.PlayfilteredButton.Text = 'Play filtered';
            app.StopButton = uibutton(app.TestcombfilterPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearallButton = uibutton(app.TestcombfilterPanel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.Lamp = uilamp(app.TestcombfilterPanel);
            app.PhasealignmentTab_2 = uitab(app.TabGroup);
            app.PhasealignmentTab_2.Title = 'Phase alignment';
            app.Signal1EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Signal1EditField_2Label.Text = 'Signal 1';
            app.Signal1EditField_2 = uieditfield(app.PhasealignmentTab_2, 'text');
            app.Signal1EditField_2.Editable = 'off';
            app.Signal2EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Signal2EditField_2Label.Text = 'Signal 2';
            app.Signal2EditField_2 = uieditfield(app.PhasealignmentTab_2, 'text');
            app.Signal2EditField_2.Editable = 'off';
            app.Signal3EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Signal3EditField_2Label.Text = 'Signal 3';
            app.Signal3EditField_2 = uieditfield(app.PhasealignmentTab_2, 'text');
            app.Signal3EditField_2.Editable = 'off';
            app.Signal4EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Signal4EditField_2Label.Text = 'Signal 4';
            app.Signal4EditField_2 = uieditfield(app.PhasealignmentTab_2, 'text');
            app.Signal4EditField_2.Editable = 'off';
            app.OpenfileButton_6 = uibutton(app.PhasealignmentTab_2, 'push');
            app.OpenfileButton_6.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButton_6Pushed, true);
            app.OpenfileButton_6.Text = 'Open file';
            app.OpenfileButton_7 = uibutton(app.PhasealignmentTab_2, 'push');
            app.OpenfileButton_7.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButton_7Pushed, true);
            app.OpenfileButton_7.Text = 'Open file';
            app.OpenfileButton_8 = uibutton(app.PhasealignmentTab_2, 'push');
            app.OpenfileButton_8.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButton_8Pushed, true);
            app.OpenfileButton_8.Text = 'Open file';
            app.OpenfileButton_9 = uibutton(app.PhasealignmentTab_2, 'push');
            app.OpenfileButton_9.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButton_9Pushed, true);
            app.OpenfileButton_9.Text = 'Open file';
            app.SamplingfreqButtonGroup_3 = uibuttongroup(app.PhasealignmentTab_2);
            app.SamplingfreqButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @SamplingfreqButtonGroup_3SelectionChanged, true);
            app.SamplingfreqButtonGroup_3.Title = 'Sampling freq';
            app.kHzButton_5 = uitogglebutton(app.SamplingfreqButtonGroup_3);
            app.kHzButton_5.Text = '44.1 kHz';
            app.kHzButton_5.Value = true;
            app.kHzButton_6 = uitogglebutton(app.SamplingfreqButtonGroup_3);
            app.kHzButton_6.Text = '48 kHz';
            app.Correlation12EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Correlation12EditField_2Label.Text = 'Correlation 1-2';
            app.Correlation12EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.Correlation12EditField_2.Editable = 'off';
            app.Correlation13EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Correlation13EditField_2Label.Text = 'Correlation 1-3';
            app.Correlation13EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.Correlation13EditField_2.Editable = 'off';
            app.Correlation23EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Correlation23EditField_2Label.Text = 'Correlation 2-3';
            app.Correlation23EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.Correlation23EditField_2.Editable = 'off';
            app.Correlation14EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Correlation14EditField_2Label.Text = 'Correlation 1-4';
            app.Correlation14EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.Correlation14EditField_2.Editable = 'off';
            app.ClearallButton_3 = uibutton(app.PhasealignmentTab_2, 'push');
            app.ClearallButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearallButton_2Pushed, true);
            app.ClearallButton_3.Text = 'Clear all';
            app.AnalyseButton_2 = uibutton(app.PhasealignmentTab_2, 'push');
            app.AnalyseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButton_2Pushed, true);
            app.AnalyseButton_2.Text = 'Analyse';
            app.AlignButton_2 = uibutton(app.PhasealignmentTab_2, 'push');
            app.AlignButton_2.Text = 'Align';
            app.TestPanel_2 = uipanel(app.PhasealignmentTab_2);
            app.TestPanel_2.Title = 'Test';
            app.MixButton = uibutton(app.TestPanel_2, 'push');
            app.MixButton.Text = 'Mix';
            app.MixButton_2 = uibutton(app.TestPanel_2, 'push');
            app.MixButton_2.Text = 'Mix';
            app.OriginalsignalsEditFieldLabel = uilabel(app.TestPanel_2);
            app.OriginalsignalsEditFieldLabel.Text = 'Original signals';
            app.OriginalsignalsEditField = uieditfield(app.TestPanel_2, 'text');
            app.OriginalsignalsEditField.Editable = 'off';
            app.AlignedsignalsEditFieldLabel = uilabel(app.TestPanel_2);
            app.AlignedsignalsEditFieldLabel.Text = 'Aligned signals';
            app.AlignedsignalsEditField = uieditfield(app.TestPanel_2, 'text');
            app.AlignedsignalsEditField.Editable = 'off';
            app.Correlation34EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.Correlation34EditField_2Label.Text = 'Correlation 3-4';
            app.Correlation34EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.Correlation34EditField_2.Editable = 'off';
            app.DelaySignal1EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.DelaySignal1EditField_2Label.Text = 'Delay Signal 1';
            app.DelaySignal1EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.DelaySignal1EditField_2.Limits = [0 Inf];
            app.DelaySignal1EditField_2.ValueDisplayFormat = '%.0f';
            app.DelaySignal1EditField_2.Editable = 'off';
            app.DelaySignal2EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.DelaySignal2EditField_2Label.Text = 'Delay Signal 2';
            app.DelaySignal2EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.DelaySignal2EditField_2.Limits = [0 Inf];
            app.DelaySignal2EditField_2.ValueDisplayFormat = '%.0f';
            app.DelaySignal2EditField_2.Editable = 'off';
            app.DelaySignal3EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.DelaySignal3EditField_2Label.Text = 'Delay Signal 3';
            app.DelaySignal3EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.DelaySignal3EditField_2.Limits = [0 Inf];
            app.DelaySignal3EditField_2.ValueDisplayFormat = '%.0f';
            app.DelaySignal3EditField_2.Editable = 'off';
            app.DelaySignal4EditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.DelaySignal4EditField_2Label.Text = 'Delay Signal 4';
            app.DelaySignal4EditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.DelaySignal4EditField_2.Limits = [0 Inf];
            app.DelaySignal4EditField_2.ValueDisplayFormat = '%.0f';
            app.DelaySignal4EditField_2.Editable = 'off';
            app.samplesLabel_2 = uilabel(app.PhasealignmentTab_2);
            app.samplesLabel_2.Text = 'samples';
            app.samplesLabel_3 = uilabel(app.PhasealignmentTab_2);
            app.samplesLabel_3.Text = 'samples';
            app.samplesLabel_4 = uilabel(app.PhasealignmentTab_2);
            app.samplesLabel_4.Text = 'samples';
            app.samplesLabel_5 = uilabel(app.PhasealignmentTab_2);
            app.samplesLabel_5.Text = 'samples';
            app.msEditField_2Label = uilabel(app.PhasealignmentTab_2);
            app.msEditField_2Label.Text = 'ms';
            app.msEditField_2 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.msEditField_2.ValueDisplayFormat = '%.1f';
            app.msEditField_2.Editable = 'off';
            app.msEditField_3Label = uilabel(app.PhasealignmentTab_2);
            app.msEditField_3Label.Text = 'ms';
            app.msEditField_3 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.msEditField_3.ValueDisplayFormat = '%.1f';
            app.msEditField_3.Editable = 'off';
            app.msEditField_4Label = uilabel(app.PhasealignmentTab_2);
            app.msEditField_4Label.Text = 'ms';
            app.msEditField_4 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.msEditField_4.ValueDisplayFormat = '%.1f';
            app.msEditField_4.Editable = 'off';
            app.msEditField_5Label = uilabel(app.PhasealignmentTab_2);
            app.msEditField_5Label.Text = 'ms';
            app.msEditField_5 = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.msEditField_5.ValueDisplayFormat = '%.1f';
            app.msEditField_5.Editable = 'off';
            app.AlignedSignal1EditFieldLabel = uilabel(app.PhasealignmentTab_2);
            app.AlignedSignal1EditFieldLabel.Text = 'Aligned Signal 1';
            app.AlignedSignal1EditField = uieditfield(app.PhasealignmentTab_2, 'text');
            app.AlignedSignal1EditField.Editable = 'off';
            app.AlignedSignal2EditFieldLabel = uilabel(app.PhasealignmentTab_2);
            app.AlignedSignal2EditFieldLabel.Text = 'Aligned Signal 2';
            app.AlignedSignal2EditField = uieditfield(app.PhasealignmentTab_2, 'text');
            app.AlignedSignal2EditField.Editable = 'off';
            app.AlignedSignal3EditFieldLabel = uilabel(app.PhasealignmentTab_2);
            app.AlignedSignal3EditFieldLabel.Text = 'Aligned Signal 3';
            app.AlignedSignal3EditField = uieditfield(app.PhasealignmentTab_2, 'text');
            app.AlignedSignal3EditField.Editable = 'off';
            app.AlignedSignal4EditFieldLabel = uilabel(app.PhasealignmentTab_2);
            app.AlignedSignal4EditFieldLabel.Text = 'Aligned Signal 4';
            app.AlignedSignal4EditField = uieditfield(app.PhasealignmentTab_2, 'text');
            app.AlignedSignal4EditField.Editable = 'off';
            app.Lamp_2 = uilamp(app.PhasealignmentTab_2);
            app.Lamp_3 = uilamp(app.PhasealignmentTab_2);
            app.Lamp_4 = uilamp(app.PhasealignmentTab_2);
            app.Lamp_5 = uilamp(app.PhasealignmentTab_2);
            app.MaxmicdistancemEditFieldLabel = uilabel(app.PhasealignmentTab_2);
            app.MaxmicdistancemEditFieldLabel.Text = 'Max mic distance (m):';
            app.MaxmicdistancemEditField = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.MaxmicdistancemEditField.Limits = [0 5];
            app.MaxmicdistancemEditField.ValueChangedFcn = createCallbackFcn(app, @MaxmicdistancemEditFieldValueChanged, true);
            app.MaxmicdistancemEditField.Value = 1;
            app.RoomtemperatureEditFieldLabel = uilabel(app.PhasealignmentTab_2);
            app.RoomtemperatureEditFieldLabel.Text = 'Room temperature';
            app.RoomtemperatureEditField = uieditfield(app.PhasealignmentTab_2, 'numeric');
            app.RoomtemperatureEditField.Limits = [0 40];
            app.RoomtemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @RoomtemperatureEditFieldValueChanged, true);
            app.RoomtemperatureEditField.Value = 20;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'At 20°C (68°F) the speed of sound is 343 m/s'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = comb_filter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end